classdef Biseccion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        IngreselafuncinEditField       matlab.ui.control.EditField
        IngreselafuncinEditFieldLabel  matlab.ui.control.Label
        IngreseporcentajedeerrorEditField_2Label  matlab.ui.control.Label
        Ingreseporcentajedeerror       matlab.ui.control.NumericEditField
        IngreseelvalordeXsEditField_2Label  matlab.ui.control.Label
        IngreseelvalordeXs             matlab.ui.control.NumericEditField
        IngreseelvalordeXi             matlab.ui.control.NumericEditField
        IngreseelvalordeXiEditField_2Label  matlab.ui.control.Label
        VolverButton                   matlab.ui.control.Button
        RealizarmtodoButton            matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        BiseccinLabel                  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
            delete(app)
            EcuNoLinealesMetCerrados
        end

        % Button pushed function: RealizarmtodoButton
        function RealizarmtodoButtonPushed(app, event)
            app.UITable.ColumnFormat = {'long','long','long','long','long','long','long','long'};
            xi=app.IngreseelvalordeXi.Value; %intervalo inferior %1
            xs=app.IngreseelvalordeXs.Value; %intervalo inferior %2
            error=app.Ingreseporcentajedeerror.Value;  %1
            %Obtenemos la funcion del campo de texto y de una vez la
            %convertimos a variable simbolica con la funcion str2sym
            f=convertStringsToChars(app.IngreselafuncinEditField.Value); %exp(1)^x-log(x)-5
            M1 = BiseccionMetodo(f,xi,xs,error);
            app.UITable.Data = M1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BiseccinLabel = uilabel(app.UIFigure);
            app.BiseccinLabel.Text = 'Bisección';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Iteración'; 'Xi'; 'Xr'; 'Xs'; 'f(Xi)'; 'f(Xr)'; 'f(Xs)'; 'Error normalizado (%)'};
            app.UITable.RowName = {};
            app.UITable.Tag = 'table';
            app.RealizarmtodoButton = uibutton(app.UIFigure, 'push');
            app.RealizarmtodoButton.ButtonPushedFcn = createCallbackFcn(app, @RealizarmtodoButtonPushed, true);
            app.RealizarmtodoButton.Tag = 'btnRealizarMetodo';
            app.RealizarmtodoButton.Text = 'Realizar método';
            app.VolverButton = uibutton(app.UIFigure, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Tag = 'btnVolver';
            app.VolverButton.Text = 'Volver';
            app.IngreseelvalordeXiEditField_2Label = uilabel(app.UIFigure);
            app.IngreseelvalordeXiEditField_2Label.Text = 'Ingrese el valor de Xi';
            app.IngreseelvalordeXi = uieditfield(app.UIFigure, 'numeric');
            app.IngreseelvalordeXi.Value = 1;
            app.IngreseelvalordeXs = uieditfield(app.UIFigure, 'numeric');
            app.IngreseelvalordeXs.Value = 2;
            app.IngreseelvalordeXsEditField_2Label = uilabel(app.UIFigure);
            app.IngreseelvalordeXsEditField_2Label.Text = 'Ingrese el valor de Xs';
            app.Ingreseporcentajedeerror = uieditfield(app.UIFigure, 'numeric');
            app.Ingreseporcentajedeerror.Value = 1;
            app.IngreseporcentajedeerrorEditField_2Label = uilabel(app.UIFigure);
            app.IngreseporcentajedeerrorEditField_2Label.Text = 'Ingrese porcentaje de error';
            app.IngreselafuncinEditFieldLabel = uilabel(app.UIFigure);
            app.IngreselafuncinEditFieldLabel.Text = 'Ingrese la función';
            app.IngreselafuncinEditField = uieditfield(app.UIFigure, 'text');
            app.IngreselafuncinEditField.Value = 'exp(1)^x-log(x)-5';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Biseccion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end