classdef Derivada < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        valordeh                       matlab.ui.control.NumericEditField
        IngreseelvalordehEditFieldLabel  matlab.ui.control.Label
        valordeXi                      matlab.ui.control.NumericEditField
        IngreseelvalordeXiEditField_2Label  matlab.ui.control.Label
        VolverButton                   matlab.ui.control.Button
        TablaDerivada                  matlab.ui.control.Table
        DerivadaDropDown               matlab.ui.control.DropDown
        DerivadaDropDownLabel          matlab.ui.control.Label
        Ingreselafuncion               matlab.ui.control.EditField
        IngreselafuncinEditFieldLabel  matlab.ui.control.Label
        RealizarButton                 matlab.ui.control.Button
        DerivadasLabel                 matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RealizarButton
        function RealizarButtonPushed(app, event)
            app.TablaDerivada.ColumnFormat = {'char','long'};
            xi = app.valordeXi.Value;
            h = app.valordeh.Value;
            funcion = app.Ingreselafuncion.Value;
            opcion = app.DerivadaDropDown.Value;
            
            if strcmp(opcion,'Primera derivada')
                %strcmp para comparar los dos valores
                [fxCentrada,errorCentrada]= PrimeraDerivada(funcion,h,xi);
            end
            if strcmp(opcion,'Segunda derivada')
                [fxCentrada,errorCentrada]= SegundaDerivada(funcion,h,xi);
            end
            if strcmp(opcion,'Tercera derivada')
                [fxCentrada,errorCentrada]= TerceraDerivada(funcion,h,xi);
            end
            if strcmp(opcion,'Cuarta derivada')
                [fxCentrada,errorCentrada]= CuartaDerivada(funcion,h,xi);
            end

            app.TablaDerivada.Data = {'Derivada',fxCentrada
                                      'Error Real',errorCentrada};
        end

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
            delete(app)
            MENU
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DerivadasLabel = uilabel(app.UIFigure);
            app.DerivadasLabel.Text = 'Derivadas';
            app.RealizarButton = uibutton(app.UIFigure, 'push');
            app.RealizarButton.ButtonPushedFcn = createCallbackFcn(app, @RealizarButtonPushed, true);
            app.RealizarButton.Tag = 'btnRealizar';
            app.RealizarButton.Text = 'Realizar';
            app.IngreselafuncinEditFieldLabel = uilabel(app.UIFigure);
            app.IngreselafuncinEditFieldLabel.Text = 'Ingrese la función:';
            app.Ingreselafuncion = uieditfield(app.UIFigure, 'text');
            app.Ingreselafuncion.Tag = 'txtFuncion';
            app.DerivadaDropDownLabel = uilabel(app.UIFigure);
            app.DerivadaDropDownLabel.Text = 'Derivada';
            app.DerivadaDropDown = uidropdown(app.UIFigure);
            app.DerivadaDropDown.Items = {'Primera derivada', 'Segunda derivada', 'Tercera derivada', 'Cuarta derivada'};
            app.DerivadaDropDown.Value = 'Primera derivada';
            app.TablaDerivada = uitable(app.UIFigure);
            app.TablaDerivada.ColumnName = {''; 'Centrada'};
            app.TablaDerivada.RowName = {};
            app.VolverButton = uibutton(app.UIFigure, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Tag = 'btnRealizar';
            app.VolverButton.Text = 'Volver';
            app.IngreseelvalordeXiEditField_2Label = uilabel(app.UIFigure);
            app.IngreseelvalordeXiEditField_2Label.Text = 'Ingrese el valor de Xi:';
            app.valordeXi = uieditfield(app.UIFigure, 'numeric');
            app.IngreseelvalordehEditFieldLabel = uilabel(app.UIFigure);
            app.IngreseelvalordehEditFieldLabel.Text = 'Ingrese el valor de h:';
            app.valordeh = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Derivada

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end