classdef FalsaPosicion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RealizarButton                 matlab.ui.control.Button
        VolverButton                   matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        IngreseelporcentajedeerrorEditField  matlab.ui.control.EditField
        IngreseelporcentajedeerrorEditFieldLabel  matlab.ui.control.Label
        IngreseelvalordeXsEditField    matlab.ui.control.EditField
        IngreseelvalordeXsEditFieldLabel  matlab.ui.control.Label
        IngreseelvalordeXiEditField    matlab.ui.control.EditField
        IngreseelvalordeXiEditFieldLabel  matlab.ui.control.Label
        IngreselafuncinEditField       matlab.ui.control.EditField
        IngreselafuncinEditFieldLabel  matlab.ui.control.Label
        FalsaposicinLabel              matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
            delete(app)
            EcuNoLinealesMetCerrados
        end

        % Button pushed function: RealizarButton
        function RealizarButtonPushed(app, event)
            app.UITable.ColumnFormat = {'long','long','long','long','long','long','long','long'};
            xi = convertStringsToChars(app.IngreseelvalordeXiEditField.Value);
            xs = convertStringsToChars(app.IngreseelvalordeXsEditField.Value);
            funcionMatematica = convertStringsToChars(app.IngreselafuncinEditField.Value);
            criterioParada = convertStringsToChars(app.IngreseelporcentajedeerrorEditField.Value);
            M1 = FalsaPosicionMetodo(funcionMatematica,xi,xs,criterioParada);
            app.UITable.Data = M1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FalsaposicinLabel = uilabel(app.UIFigure);
            app.FalsaposicinLabel.Text = 'Falsa posición';
            app.IngreselafuncinEditFieldLabel = uilabel(app.UIFigure);
            app.IngreselafuncinEditFieldLabel.Text = 'Ingrese la función:';
            app.IngreselafuncinEditField = uieditfield(app.UIFigure, 'text');
            app.IngreselafuncinEditField.Tag = 'txtFuncion';
            app.IngreselafuncinEditField.Value = 'exp(1)^x-log(x)-5';
            app.IngreseelvalordeXiEditFieldLabel = uilabel(app.UIFigure);
            app.IngreseelvalordeXiEditFieldLabel.Text = 'Ingrese el valor de Xi:';
            app.IngreseelvalordeXiEditField = uieditfield(app.UIFigure, 'text');
            app.IngreseelvalordeXiEditField.Tag = 'txtXi';
            app.IngreseelvalordeXiEditField.Value = '1';
            app.IngreseelvalordeXsEditFieldLabel = uilabel(app.UIFigure);
            app.IngreseelvalordeXsEditFieldLabel.Text = 'Ingrese el valor de Xs:';
            app.IngreseelvalordeXsEditField = uieditfield(app.UIFigure, 'text');
            app.IngreseelvalordeXsEditField.Tag = 'txtXs';
            app.IngreseelvalordeXsEditField.Value = '2';
            app.IngreseelporcentajedeerrorEditFieldLabel = uilabel(app.UIFigure);
            app.IngreseelporcentajedeerrorEditFieldLabel.Text = 'Ingrese el porcentaje de error:';
            app.IngreseelporcentajedeerrorEditField = uieditfield(app.UIFigure, 'text');
            app.IngreseelporcentajedeerrorEditField.Tag = 'txtError';
            app.IngreseelporcentajedeerrorEditField.Value = '0.02';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Iteración'; 'Xi'; 'Xr'; 'Xs'; 'f(Xi)'; 'f(Xr)'; 'f(Xs)'; 'Error Normalizado%'};
            app.UITable.RowName = {};
            app.UITable.Tag = 'tabla';
            app.VolverButton = uibutton(app.UIFigure, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Tag = 'btnVolver';
            app.VolverButton.Text = 'Volver';
            app.RealizarButton = uibutton(app.UIFigure, 'push');
            app.RealizarButton.ButtonPushedFcn = createCallbackFcn(app, @RealizarButtonPushed, true);
            app.RealizarButton.Tag = 'btnRealizar';
            app.RealizarButton.Text = 'Realizar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FalsaPosicion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end