classdef Isimpson13 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UITable               matlab.ui.control.Table
        volverButton          matlab.ui.control.Button
        ResolverButton        matlab.ui.control.Button
        nEditField            matlab.ui.control.EditField
        nEditFieldLabel       matlab.ui.control.Label
        bEditField            matlab.ui.control.EditField
        bEditFieldLabel       matlab.ui.control.Label
        aEditField            matlab.ui.control.EditField
        aEditFieldLabel       matlab.ui.control.Label
        Simpson13Label        matlab.ui.control.Label
        FuncinEditField       matlab.ui.control.EditField
        FuncinEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ResolverButton
        function ResolverButtonPushed(app, event)
             app.UITable.ColumnFormat = {'long','long','long','long','long'};
            a = convertStringsToChars(app.aEditField.Value);
            b = convertStringsToChars(app.bEditField.Value);
            n = convertStringsToChars(app.nEditField.Value);
            funcionMatematica = convertStringsToChars(app.FuncinEditField.Value);
            [valor,valorReal,errorReal]= simpson13Metodo(funcionMatematica,a,b,n);
            app.UITable.Data = {valor,valorReal,errorReal};
        end

        % Button pushed function: volverButton
        function volverButtonPushed(app, event)
            delete(app)
            MetodosIntegrales
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FuncinEditFieldLabel = uilabel(app.UIFigure);
            app.FuncinEditFieldLabel.Text = 'Función:';
            app.FuncinEditField = uieditfield(app.UIFigure, 'text');
            app.Simpson13Label = uilabel(app.UIFigure);
            app.Simpson13Label.Text = 'Simpson 1/3';
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a:';
            app.aEditField = uieditfield(app.UIFigure, 'text');
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b:';
            app.bEditField = uieditfield(app.UIFigure, 'text');
            app.nEditFieldLabel = uilabel(app.UIFigure);
            app.nEditFieldLabel.Text = 'n:';
            app.nEditField = uieditfield(app.UIFigure, 'text');
            app.ResolverButton = uibutton(app.UIFigure, 'push');
            app.ResolverButton.ButtonPushedFcn = createCallbackFcn(app, @ResolverButtonPushed, true);
            app.ResolverButton.Text = 'Resolver';
            app.volverButton = uibutton(app.UIFigure, 'push');
            app.volverButton.ButtonPushedFcn = createCallbackFcn(app, @volverButtonPushed, true);
            app.volverButton.Text = 'volver';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Valor obtenido'; 'Valor real'; 'Error real'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Isimpson13

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end