classdef Lagrange < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Numerodedatos                matlab.ui.control.NumericEditField
        NumerodedatosEditFieldLabel  matlab.ui.control.Label
        Y6                           matlab.ui.control.NumericEditField
        Y5                           matlab.ui.control.NumericEditField
        Y4                           matlab.ui.control.NumericEditField
        Y3                           matlab.ui.control.NumericEditField
        Y2                           matlab.ui.control.NumericEditField
        Y1                           matlab.ui.control.NumericEditField
        X6                           matlab.ui.control.NumericEditField
        X5                           matlab.ui.control.NumericEditField
        X4                           matlab.ui.control.NumericEditField
        X3                           matlab.ui.control.NumericEditField
        ResultadoEditFieldLabel      matlab.ui.control.Label
        X2                           matlab.ui.control.NumericEditField
        X1                           matlab.ui.control.NumericEditField
        EvaluarFxEditFieldLabel      matlab.ui.control.Label
        IngreselosvaloresenYLabel    matlab.ui.control.Label
        ResultFx                     matlab.ui.control.EditField
        FxEditFieldLabel             matlab.ui.control.Label
        VolverButton                 matlab.ui.control.Button
        EvaluarFx                    matlab.ui.control.NumericEditField
        ResolverMetodo               matlab.ui.control.Button
        Resultado                    matlab.ui.control.NumericEditField
        IngreselosvaloresenXLabel    matlab.ui.control.Label
        LagrangeLabel                matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ResolverMetodo
        function ResolverMetodoPushed(app, event)
            n = app.Numerodedatos.Value;
            x1 = app.X1.Value;
            x2 = app.X2.Value;
            x3 = app.X3.Value;
            x4 = app.X4.Value;
            x5 = app.X5.Value;
            x6 = app.X6.Value;

            y1 = app.Y1.Value;
            y2 = app.Y2.Value;
            y3 = app.Y3.Value;
            y4 = app.Y4.Value;
            y5 = app.Y5.Value;
            y6 = app.Y6.Value;

            if n==1
                valoresX = x1;
                valoresY = y1;
            end
            if n==2
                valoresX = [x1 x2];
                valoresY = [y1 y2];
            end
            if n==3
                valoresX = [x1 x2 x3];
                valoresY = [y1 y2 y3];
            end
            if n==4
                valoresX = [x1 x2 x3 x4];
                valoresY = [y1 y2 y3 y4]; 
            end
            if n==5
                valoresX = [x1 x2 x3 x4 x5];
                valoresY = [y1 y2 y3 y4 y5];
            end
            if n==6
                valoresX = [x1 x2 x3 x4 x5 x6];
                valoresY = [y1 y2 y3 y4 y5 y6];
            end
            resultado = LagrangeMetodo(valoresX,valoresY);
            app.ResultFx.Value = char(resultado);
            t=app.EvaluarFx.Value;
            resultado = eval(resultado);
            app.Resultado.Value = resultado;
        end

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
            interpolacion
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LagrangeLabel = uilabel(app.UIFigure);
            app.LagrangeLabel.Text = 'Lagrange';
            app.IngreselosvaloresenXLabel = uilabel(app.UIFigure);
            app.IngreselosvaloresenXLabel.Text = 'Ingrese los valores en X ';
            app.Resultado = uieditfield(app.UIFigure, 'numeric');
            app.Resultado.Editable = 'off';
            app.ResolverMetodo = uibutton(app.UIFigure, 'push');
            app.ResolverMetodo.ButtonPushedFcn = createCallbackFcn(app, @ResolverMetodoPushed, true);
            app.ResolverMetodo.Text = 'Resolver método';
            app.EvaluarFx = uieditfield(app.UIFigure, 'numeric');
            app.VolverButton = uibutton(app.UIFigure, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Text = 'Volver';
            app.FxEditFieldLabel = uilabel(app.UIFigure);
            app.FxEditFieldLabel.Text = 'F(x)';
            app.ResultFx = uieditfield(app.UIFigure, 'text');
            app.ResultFx.Editable = 'off';
            app.IngreselosvaloresenYLabel = uilabel(app.UIFigure);
            app.IngreselosvaloresenYLabel.Text = 'Ingrese los valores en Y ';
            app.EvaluarFxEditFieldLabel = uilabel(app.UIFigure);
            app.EvaluarFxEditFieldLabel.Text = 'Evaluar F(x)';
            app.X1 = uieditfield(app.UIFigure, 'numeric');
            app.X1.Value = -3;
            app.X2 = uieditfield(app.UIFigure, 'numeric');
            app.X2.Value = -1;
            app.ResultadoEditFieldLabel = uilabel(app.UIFigure);
            app.ResultadoEditFieldLabel.Text = 'Resultado ';
            app.X3 = uieditfield(app.UIFigure, 'numeric');
            app.X3.Value = 2;
            app.X4 = uieditfield(app.UIFigure, 'numeric');
            app.X4.Value = 4;
            app.X5 = uieditfield(app.UIFigure, 'numeric');
            app.X5.Value = 7;
            app.X6 = uieditfield(app.UIFigure, 'numeric');
            app.Y1 = uieditfield(app.UIFigure, 'numeric');
            app.Y1.Value = 2;
            app.Y2 = uieditfield(app.UIFigure, 'numeric');
            app.Y2.Value = 7;
            app.Y3 = uieditfield(app.UIFigure, 'numeric');
            app.Y3.Value = -2;
            app.Y4 = uieditfield(app.UIFigure, 'numeric');
            app.Y4.Value = 5;
            app.Y5 = uieditfield(app.UIFigure, 'numeric');
            app.Y5.Value = 8;
            app.Y6 = uieditfield(app.UIFigure, 'numeric');
            app.NumerodedatosEditFieldLabel = uilabel(app.UIFigure);
            app.NumerodedatosEditFieldLabel.Text = 'Numero de datos';
            app.Numerodedatos = uieditfield(app.UIFigure, 'numeric');
            app.Numerodedatos.Value = 5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lagrange

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end