classdef NewtonRaphson < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        IngreseelporcentajedeerrorEditField  matlab.ui.control.EditField
        IngreseelporcentajedeerrorEditField_3Label  matlab.ui.control.Label
        IngreseelpuntodeinicioEditField  matlab.ui.control.EditField
        IngreseelpuntodeinicioEditField_3Label  matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        IngreselafuncionEditField      matlab.ui.control.EditField
        IngreselafuncinEditFieldLabel  matlab.ui.control.Label
        RealizarButton                 matlab.ui.control.Button
        VolverButton                   matlab.ui.control.Button
        NewtonRaphsonLabel             matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
            delete(app)
            EcuNoLinealesMetAbiertos
        end

        % Button pushed function: RealizarButton
        function RealizarButtonPushed(app, event)
            app.UITable.ColumnFormat = {'long','long','long','long','long','long'};

            funcion=convertStringsToChars(app.IngreselafuncionEditField.Value);
            valorInicial = convertStringsToChars(app.IngreseelpuntodeinicioEditField.Value);
            criterioParada = convertStringsToChars(app.IngreseelporcentajedeerrorEditField.Value);
            
            M1 = NewtonRaphsonMetodo(funcion,valorInicial,criterioParada);
            app.UITable.Data = M1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NewtonRaphsonLabel = uilabel(app.UIFigure);
            app.NewtonRaphsonLabel.Text = 'Newton Raphson';
            app.VolverButton = uibutton(app.UIFigure, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Tag = 'btnVolver';
            app.VolverButton.Text = 'Volver';
            app.RealizarButton = uibutton(app.UIFigure, 'push');
            app.RealizarButton.ButtonPushedFcn = createCallbackFcn(app, @RealizarButtonPushed, true);
            app.RealizarButton.Tag = 'btnRealizar';
            app.RealizarButton.Text = 'Realizar';
            app.IngreselafuncinEditFieldLabel = uilabel(app.UIFigure);
            app.IngreselafuncinEditFieldLabel.Text = 'Ingrese la función:';
            app.IngreselafuncionEditField = uieditfield(app.UIFigure, 'text');
            app.IngreselafuncionEditField.Tag = 'txtFuncion';
            app.IngreselafuncionEditField.Value = 'exp(1)^x-log(x)-5';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Iteración'; 'Xi+1'; 'xi'; 'f(Xi)'; 'f´(Xi)'; 'Error normalizado%'};
            app.UITable.RowName = {};
            app.UITable.Tag = 'tabla';
            app.IngreseelpuntodeinicioEditField_3Label = uilabel(app.UIFigure);
            app.IngreseelpuntodeinicioEditField_3Label.Text = 'Ingrese el punto de inicio:';
            app.IngreseelpuntodeinicioEditField = uieditfield(app.UIFigure, 'text');
            app.IngreseelpuntodeinicioEditField.Value = '1';
            app.IngreseelporcentajedeerrorEditField_3Label = uilabel(app.UIFigure);
            app.IngreseelporcentajedeerrorEditField_3Label.Text = 'Ingrese el porcentaje de error:';
            app.IngreseelporcentajedeerrorEditField = uieditfield(app.UIFigure, 'text');
            app.IngreseelporcentajedeerrorEditField.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewtonRaphson

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end