classdef PuntoFijo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        IngreselafunciongxEditField  matlab.ui.control.EditField
        IngreselafunciongxEditFieldLabel  matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        IngreseporcentajedeerrorEditField  matlab.ui.control.EditField
        IngreseporcentajedeerrorEditFieldLabel  matlab.ui.control.Label
        IngreseelvalordeX0EditField  matlab.ui.control.EditField
        IngreseelvalordeX0EditFieldLabel  matlab.ui.control.Label
        IngreselafuncinfxEditField   matlab.ui.control.EditField
        IngreselafuncinfxEditFieldLabel  matlab.ui.control.Label
        VolverButton                 matlab.ui.control.Button
        RealizarmtodoButton          matlab.ui.control.Button
        PuntofijoLabel               matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
            delete(app)
            EcuNoLinealesMetAbiertos
        end

        % Button pushed function: RealizarmtodoButton
        function RealizarmtodoButtonPushed(app, event)
           app.UITable.ColumnFormat = {'long','long','long','long','long','long','long','long'};
           xi = convertStringsToChars(app.IngreseelvalordeX0EditField.Value);
           funcionMatematicaGx = convertStringsToChars(app.IngreselafunciongxEditField.Value);
           funcionMatematicaFx = convertStringsToChars(app.IngreselafuncinfxEditField.Value);
           criterioParada = convertStringsToChars(app.IngreseporcentajedeerrorEditField.Value);
           [M1] = puntoFijoMetodo(funcionMatematicaFx,funcionMatematicaGx,xi,criterioParada);
           app.UITable.Data = M1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PuntofijoLabel = uilabel(app.UIFigure);
            app.PuntofijoLabel.Text = 'Punto fijo';
            app.RealizarmtodoButton = uibutton(app.UIFigure, 'push');
            app.RealizarmtodoButton.ButtonPushedFcn = createCallbackFcn(app, @RealizarmtodoButtonPushed, true);
            app.RealizarmtodoButton.Tag = 'btnRealizarMetodo';
            app.RealizarmtodoButton.Text = 'Realizar método';
            app.VolverButton = uibutton(app.UIFigure, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Tag = 'btnVolver';
            app.VolverButton.Text = 'Volver';
            app.IngreselafuncinfxEditFieldLabel = uilabel(app.UIFigure);
            app.IngreselafuncinfxEditFieldLabel.Text = 'Ingrese la función f(x)';
            app.IngreselafuncinfxEditField = uieditfield(app.UIFigure, 'text');
            app.IngreselafuncinfxEditField.Tag = 'txtFuncion';
            app.IngreselafuncinfxEditField.Value = 'exp(1)^x-log(x)-5';
            app.IngreseelvalordeX0EditFieldLabel = uilabel(app.UIFigure);
            app.IngreseelvalordeX0EditFieldLabel.Text = 'Ingrese el valor de X0';
            app.IngreseelvalordeX0EditField = uieditfield(app.UIFigure, 'text');
            app.IngreseelvalordeX0EditField.Tag = 'txtvlrx0';
            app.IngreseelvalordeX0EditField.Value = '1';
            app.IngreseporcentajedeerrorEditFieldLabel = uilabel(app.UIFigure);
            app.IngreseporcentajedeerrorEditFieldLabel.Text = 'Ingrese porcentaje de error';
            app.IngreseporcentajedeerrorEditField = uieditfield(app.UIFigure, 'text');
            app.IngreseporcentajedeerrorEditField.Tag = 'txtError';
            app.IngreseporcentajedeerrorEditField.Value = '1';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Iteración'; 'X'; 'g(X)'; 'f(X)'; 'Error normalizado (%)'};
            app.UITable.RowName = {};
            app.UITable.Tag = 'table';
            app.IngreselafunciongxEditFieldLabel = uilabel(app.UIFigure);
            app.IngreselafunciongxEditFieldLabel.Text = 'Ingrese la funcion g(x):';
            app.IngreselafunciongxEditField = uieditfield(app.UIFigure, 'text');
            app.IngreselafunciongxEditField.Value = 'log(log(x)+5)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PuntoFijo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end