classdef interpolacionNewton < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        VolverButton                 matlab.ui.control.Button
        ValoresenYLabel              matlab.ui.control.Label
        ValoresenXLabel              matlab.ui.control.Label
        NewtonLabel                  matlab.ui.control.Label
        CantidadPuntosInterpolacion  matlab.ui.control.NumericEditField
        NumPuntosMIN2MAX5Label       matlab.ui.control.Label
        resultadoInterpolacion       matlab.ui.control.Label
        labelRaiz_2                  matlab.ui.control.Label
        EvaluacionX                  matlab.ui.control.NumericEditField
        NumPuntosMAX5EditFieldLabel  matlab.ui.control.Label
        PolinomioAjustado            matlab.ui.control.Label
        labelRaiz                    matlab.ui.control.Label
        InterpolacionX5              matlab.ui.control.NumericEditField
        InterpolacionX4              matlab.ui.control.NumericEditField
        InterpolacionX3              matlab.ui.control.NumericEditField
        InterpolacionX2              matlab.ui.control.NumericEditField
        InterpolacionX1              matlab.ui.control.NumericEditField
        InterpolacionX0              matlab.ui.control.NumericEditField
        InterpolacionY5              matlab.ui.control.NumericEditField
        InterpolacionY4              matlab.ui.control.NumericEditField
        InterpolacionY3              matlab.ui.control.NumericEditField
        InterpolacionY2              matlab.ui.control.NumericEditField
        InterpolacionY1              matlab.ui.control.NumericEditField
        InterpolacionY0              matlab.ui.control.NumericEditField
        btnSolucionarInterpolacion   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnSolucionarInterpolacion
        function btnSolucionarInterpolacionButtonPushed(app, event)
            syms x;
            n = app.CantidadPuntosInterpolacion.Value;
            xEval = app.EvaluacionX.Value;

            x0 = app.InterpolacionX0.Value;
            x1 = app.InterpolacionX1.Value;
            x2 = app.InterpolacionX2.Value;
            x3 = app.InterpolacionX3.Value;
            x4 = app.InterpolacionX4.Value;
            x5 = app.InterpolacionX5.Value;

            y0 = app.InterpolacionY0.Value;
            y1 = app.InterpolacionY1.Value;
            y2 = app.InterpolacionY2.Value;
            y3 = app.InterpolacionY3.Value;
            y4 = app.InterpolacionY4.Value;
            y5 = app.InterpolacionY5.Value;

            if(n == 2)
                fx0 = y0;
                fx1 = y1;

                % Primeras Diferencias
                fx0x1 = double((fx1 - fx0)/(x1 - x0));
                b0 = fx0;
                b1 = fx0x1;
                resultado = double(b0 + b1 * (xEval - x0));
                b0 = num2str(b0);
                b1 = num2str(b1);
                x0 = num2str(x0);
                
                strPolinomio = strcat(b0, "+ (", b1, ") * ( x - (", x0 , "))");
                
            elseif(n == 3)
                fx0 = y0;
                fx1 = y1;
                fx2 = y2;

                %Primeras Diferencias
                fx0x1 = double((fx1 - fx0)/(x1 - x0));
                fx1x2 = double((fx2 - fx1)/(x2 - x1));

                %Segundas Diferencias
                fx0x1x2 = double((fx1x2 - fx0x1)/(x2 - x0));

                b0 = fx0;
                b1 = fx0x1;
                b2 = fx0x1x2;

                resultado = double(b0 + b1 * (xEval - x0) + b2 * (xEval - x0) * (xEval - x1));

                b0 = num2str(b0);
                b1 = num2str(b1);
                b2 = num2str(b2);
                x0 = num2str(x0);
                x1 = num2str(x1);
                
                strPolinomio = strcat(b0, " + (", b1, ") * ( x - (", x0 , "))", " + ", b2, "* (x - (", x0 , ")) * ( x - (", x1 , "))");
            elseif(n == 4)
                fx0 = y0;
                fx1 = y1;
                fx2 = y2;
                fx3 = y3;

                %Primeras Diferencias
                fx0x1 = double((fx1 - fx0)/(x1 - x0));
                fx1x2 = double((fx2 - fx1)/(x2 - x1));
                fx2x3 = double((fx3 - fx2)/(x3 - x2));

                %Segundas Diferencias
                fx0x1x2 = double((fx1x2 - fx0x1)/(x2 - x0));
                fx1x2x3 = double((fx2x3 - fx1x2)/(x3 - x1));

                % Terceras Diferencias
                fx0x1x2x3 = double((fx1x2x3 - fx0x1x2)/(x3 - x0));

                b0 = fx0;
                b1 = fx0x1;
                b2 = fx0x1x2;
                b3 = fx0x1x2x3;

                resultado = double(b0 + b1 * (xEval - x0) + ...
                                        b2 * (xEval - x0) * (xEval - x1)) + ...
                                        b3 * (xEval - x0) * (xEval - x1) * (xEval - x2);

                b0 = num2str(b0);
                b1 = num2str(b1);
                b2 = num2str(b2);
                b3 = num2str(b3);
                x0 = num2str(x0);
                x1 = num2str(x1);
                x2 = num2str(x2);

                strPolinomio = strcat(b0, " + (", b1, ") * (x - (", x0 , "))", " + (" ...
                                    , b2, ") * (x - (", x0 , ")) * (x - (", x1 , ")) + (" ...
                                    , b3 , ") * (x - (", x0 , ")) * (x - (", x1, ")) * (x - (", x2,"))");
            elseif(n == 5)
                fx0 = y0;
                fx1 = y1;
                fx2 = y2;
                fx3 = y3;
                fx4 = y4;

                %Primeras Diferencias
                fx0x1 = double((fx1 - fx0)/(x1 - x0));
                fx1x2 = double((fx2 - fx1)/(x2 - x1));
                fx2x3 = double((fx3 - fx2)/(x3 - x2));
                fx3x4 = double((fx4 - fx3)/(x4 - x3));

                %Segundas Diferencias
                fx0x1x2 = double((fx1x2 - fx0x1)/(x2 - x0));
                fx1x2x3 = double((fx2x3 - fx1x2)/(x3 - x1));
                fx2x3x4 = double((fx3x4 - fx2x3)/(x4 - x2));

                % Terceras Diferencias
                fx0x1x2x3 = double((fx1x2x3 - fx0x1x2)/(x3 - x0));
                fx1x2x3x4 = double((fx2x3x4 - fx1x2x3)/(x4 - x1));

                % Cuartas Diferencias
                fx0x1x2x3x4 = double((fx1x2x3x4 - fx0x1x2x3)/(x4 - x0));

                b0 = fx0;
                b1 = fx0x1;
                b2 = fx0x1x2;
                b3 = fx0x1x2x3;
                b4 = fx0x1x2x3x4;

                resultado = double(b0 + b1 * (xEval - x0) + ...
                                        b2 * (xEval - x0) * (xEval - x1) + ...
                                        b3 * (xEval - x0) * (xEval - x1) * (xEval - x2) + ...
                                        b4 * (xEval - x0) * (xEval - x1) * (xEval - x2) * (xEval - x3));

                b0 = num2str(b0);
                b1 = num2str(b1);
                b2 = num2str(b2);
                b3 = num2str(b3);
                b4 = num2str(b4);
                x0 = num2str(x0);
                x1 = num2str(x1);
                x2 = num2str(x2);
                x3 = num2str(x3);

                strPolinomio = strcat(b0, " + (", b1, ") * (x - (", x0 , "))", " + (" ...
                                    , b2, ") * (x - (", x0 , ")) * (x - (", x1 , ")) + (" ...
                                    , b3 , ") * (x - (", x0 , ")) * (x - (", x1, ")) * (x - (", x2,")) + (" ...
                                    ,b4,") * (x - (",x0,")) *  (x - (",x1,")) * (x - (",x2,")) * (x - (",x3,"))");
            elseif(n == 6)
                fx0 = y0;
                fx1 = y1;
                fx2 = y2;
                fx3 = y3;
                fx4 = y4;
                fx5 = y5;

                %Primeras Diferencias
                fx0x1 = double((fx1 - fx0)/(x1 - x0));
                fx1x2 = double((fx2 - fx1)/(x2 - x1));
                fx2x3 = double((fx3 - fx2)/(x3 - x2));
                fx3x4 = double((fx4 - fx3)/(x4 - x3));
                fx4x5 = double((fx5 - fx4)/(x5 - x4));

                %Segundas Diferencias
                fx0x1x2 = double((fx1x2 - fx0x1)/(x2 - x0));
                fx1x2x3 = double((fx2x3 - fx1x2)/(x3 - x1));
                fx2x3x4 = double((fx3x4 - fx2x3)/(x4 - x2));
                fx3x4x5 = double((fx4x5 - fx3x4)/(x5 - x3));

                % Terceras Diferencias
                fx0x1x2x3 = double((fx1x2x3 - fx0x1x2)/(x3 - x0));
                fx1x2x3x4 = double((fx2x3x4 - fx1x2x3)/(x4 - x1));
                fx2x3x4x5 = double((fx3x4x5 - fx2x3x4)/(x5 - x2));

                % Cuartas Diferencias
                fx0x1x2x3x4 = double((fx1x2x3x4 - fx0x1x2x3)/(x4 - x0));
                fx1x2x3x4x5 = double((fx2x3x4x5 - fx1x2x3x4)/(x5 - x1));

                %Quintas Diferencias
                fx0x1x2x3x4x5 = double((fx1x2x3x4x5 - fx0x1x2x3x4)/(x5 - x0));

                b0 = fx0;
                b1 = fx0x1;
                b2 = fx0x1x2;
                b3 = fx0x1x2x3;
                b4 = fx0x1x2x3x4;
                b5 = fx0x1x2x3x4x5;

                resultado = double(b0 + b1 * (xEval - x0) + ...
                                        b2 * (xEval - x0) * (xEval - x1) + ...
                                        b3 * (xEval - x0) * (xEval - x1) * (xEval - x2) + ...
                                        b4 * (xEval - x0) * (xEval - x1) * (xEval - x2) * (xEval - x3) + ...
                                        b5 * (xEval - x0) * (xEval - x1) * (xEval - x2) * (xEval - x3) * (xEval - x5));

                b0 = num2str(b0);
                b1 = num2str(b1);
                b2 = num2str(b2);
                b3 = num2str(b3);
                b4 = num2str(b4);
                b5 = num2str(b5);
                x0 = num2str(x0);
                x1 = num2str(x1);
                x2 = num2str(x2);
                x3 = num2str(x3);
                x4 = num2str(x4);

                strPolinomio = strcat(b0, " + (", b1, ") * (x - (", x0 , "))", " + (" ...
                                    , b2, ") * (x - (", x0 , ")) * (x - (", x1 , ")) + (" ...
                                    , b3, ") * (x - (", x0 , ")) * (x - (", x1, ")) * (x - (", x2,")) + (" ...
                                    , b4,") * (x - (",x0,")) *  (x - (",x1,")) * (x - (",x2,")) * (x - (",x3,")) + (" ...
                                    , b5, ") * (x - (",x0,")) *  (x - (",x1,")) * (x - (",x2,")) * (x - (",x3,")) * (x - (",x4,"))");
            end
            app.PolinomioAjustado.Text = strPolinomio;
            app.resultadoInterpolacion.Text = num2str(resultado);
        end

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
            delete(app)
            interpolacion
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.btnSolucionarInterpolacion = uibutton(app.UIFigure, 'push');
            app.btnSolucionarInterpolacion.ButtonPushedFcn = createCallbackFcn(app, @btnSolucionarInterpolacionButtonPushed, true);
            app.btnSolucionarInterpolacion.Tag = 'btnSolucionar';
            app.btnSolucionarInterpolacion.Text = 'Solucionar';
            app.InterpolacionY0 = uieditfield(app.UIFigure, 'numeric');
            app.InterpolacionY1 = uieditfield(app.UIFigure, 'numeric');
            app.InterpolacionY2 = uieditfield(app.UIFigure, 'numeric');
            app.InterpolacionY3 = uieditfield(app.UIFigure, 'numeric');
            app.InterpolacionY4 = uieditfield(app.UIFigure, 'numeric');
            app.InterpolacionY5 = uieditfield(app.UIFigure, 'numeric');
            app.InterpolacionX0 = uieditfield(app.UIFigure, 'numeric');
            app.InterpolacionX1 = uieditfield(app.UIFigure, 'numeric');
            app.InterpolacionX2 = uieditfield(app.UIFigure, 'numeric');
            app.InterpolacionX3 = uieditfield(app.UIFigure, 'numeric');
            app.InterpolacionX4 = uieditfield(app.UIFigure, 'numeric');
            app.InterpolacionX5 = uieditfield(app.UIFigure, 'numeric');
            app.labelRaiz = uilabel(app.UIFigure);
            app.labelRaiz.Text = 'Polinomio Ajustado:';
            app.PolinomioAjustado = uilabel(app.UIFigure);
            app.PolinomioAjustado.Text = '';
            app.NumPuntosMAX5EditFieldLabel = uilabel(app.UIFigure);
            app.NumPuntosMAX5EditFieldLabel.Text = 'X a Evaluar:';
            app.EvaluacionX = uieditfield(app.UIFigure, 'numeric');
            app.labelRaiz_2 = uilabel(app.UIFigure);
            app.labelRaiz_2.Text = 'Valor Obtenido:';
            app.resultadoInterpolacion = uilabel(app.UIFigure);
            app.resultadoInterpolacion.Text = '';
            app.NumPuntosMIN2MAX5Label = uilabel(app.UIFigure);
            app.NumPuntosMIN2MAX5Label.Text = 'Número de valores';
            app.CantidadPuntosInterpolacion = uieditfield(app.UIFigure, 'numeric');
            app.NewtonLabel = uilabel(app.UIFigure);
            app.NewtonLabel.Text = 'Newton';
            app.ValoresenXLabel = uilabel(app.UIFigure);
            app.ValoresenXLabel.Text = 'Valores en X';
            app.ValoresenYLabel = uilabel(app.UIFigure);
            app.ValoresenYLabel.Text = 'Valores en Y';
            app.VolverButton = uibutton(app.UIFigure, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Text = 'Volver';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interpolacionNewton

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end