classdef jacobi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        numerodeecuacionesEditField  matlab.ui.control.NumericEditField
        NmerodeecuacionesEditFieldLabel  matlab.ui.control.Label
        VolverButton                 matlab.ui.control.Button
        RealizarButton               matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        NmerodeiteracionesEditField  matlab.ui.control.NumericEditField
        NmerodeiteracionesEditFieldLabel  matlab.ui.control.Label
        ErrorEditField               matlab.ui.control.NumericEditField
        ErrorEditFieldLabel          matlab.ui.control.Label
        ind_5                        matlab.ui.control.NumericEditField
        ind_4                        matlab.ui.control.NumericEditField
        ind_3                        matlab.ui.control.NumericEditField
        ind_2                        matlab.ui.control.NumericEditField
        ind_1                        matlab.ui.control.NumericEditField
        IngreselamatrizdecoeficientesindependientesLabel  matlab.ui.control.Label
        E55                          matlab.ui.control.NumericEditField
        E54                          matlab.ui.control.NumericEditField
        E53                          matlab.ui.control.NumericEditField
        E52                          matlab.ui.control.NumericEditField
        E51                          matlab.ui.control.NumericEditField
        E45                          matlab.ui.control.NumericEditField
        E44                          matlab.ui.control.NumericEditField
        E43                          matlab.ui.control.NumericEditField
        E42                          matlab.ui.control.NumericEditField
        E41                          matlab.ui.control.NumericEditField
        E35                          matlab.ui.control.NumericEditField
        E34                          matlab.ui.control.NumericEditField
        E33                          matlab.ui.control.NumericEditField
        E32                          matlab.ui.control.NumericEditField
        E31                          matlab.ui.control.NumericEditField
        E25                          matlab.ui.control.NumericEditField
        E24                          matlab.ui.control.NumericEditField
        E23                          matlab.ui.control.NumericEditField
        E22                          matlab.ui.control.NumericEditField
        E21                          matlab.ui.control.NumericEditField
        E15                          matlab.ui.control.NumericEditField
        E14                          matlab.ui.control.NumericEditField
        E13                          matlab.ui.control.NumericEditField
        E12                          matlab.ui.control.NumericEditField
        E11                          matlab.ui.control.NumericEditField
        IngreselamatrizdecoeficientesLabel  matlab.ui.control.Label
        MtodoJacobiLabel             matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
            delete(app)
            Ecuaciones_lineales
        end

        % Button pushed function: RealizarButton
        function RealizarButtonPushed(app, event)
            n = app.numerodeecuacionesEditField.Value;
            if n==2
                A = [app.E11.Value app.E12.Value;
                     app.E21.Value app.E22.Value];
                b = [app.ind_1.Value app.ind_2.Value]';
                x = [1 1]';
                error = app.ErrorEditField.Value;
                m = app.NmerodeiteracionesEditField.Value;
            end
            if n==3
                A = [app.E11.Value app.E12.Value app.E13.Value;
                     app.E21.Value app.E22.Value app.E23.Value; 
                     app.E31.Value app.E32.Value app.E33.Value];
                b = [app.ind_1.Value app.ind_2.Value app.ind_3.Value]';
                x = [1 1 1]';
                error = app.ErrorEditField.Value;
                m = app.NmerodeiteracionesEditField.Value;
            end
            if n==4
                A = [app.E11.Value app.E12.Value app.E13.Value app.E14.Value;
                     app.E21.Value app.E22.Value app.E23.Value app.E24.Value; 
                     app.E31.Value app.E32.Value app.E33.Value app.E34.Value;
                     app.E41.Value app.E42.Value app.E43.Value app.E44.Value];
                b = [app.ind_1.Value app.ind_2.Value app.ind_3.Value app.ind_4.Value]';
                x = [1 1 1 1]';
                error = app.ErrorEditField.Value;
                m = app.NmerodeiteracionesEditField.Value;
            end
            if n==5
                A = [app.E11.Value app.E12.Value app.E13.Value app.E14.Value app.E15.Value;
                     app.E21.Value app.E22.Value app.E23.Value app.E24.Value app.E25.Value; 
                     app.E31.Value app.E32.Value app.E33.Value app.E34.Value app.E35.Value;
                     app.E41.Value app.E42.Value app.E43.Value app.E44.Value app.E45.Value;
                     app.E51.Value app.E52.Value app.E53.Value app.E54.Value app.E55.Value];
                b = [app.ind_1.Value app.ind_2.Value app.ind_3.Value app.ind_4.Value app.ind_5.Value]';
                x = [1 1 1 1 1]';
                error = app.ErrorEditField.Value;
                m = app.NmerodeiteracionesEditField.Value;
            end
            jacobiMetodo(A,b,x,error,m);
            % app.UITable.Data = M1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MtodoJacobiLabel = uilabel(app.UIFigure);
            app.MtodoJacobiLabel.Text = 'Método Jacobi';
            app.IngreselamatrizdecoeficientesLabel = uilabel(app.UIFigure);
            app.IngreselamatrizdecoeficientesLabel.Text = 'Ingrese la matriz de coeficientes';
            app.E11 = uieditfield(app.UIFigure, 'numeric');
            app.E11.Value = -8;
            app.E12 = uieditfield(app.UIFigure, 'numeric');
            app.E12.Value = 1;
            app.E13 = uieditfield(app.UIFigure, 'numeric');
            app.E13.Value = -2;
            app.E14 = uieditfield(app.UIFigure, 'numeric');
            app.E15 = uieditfield(app.UIFigure, 'numeric');
            app.E21 = uieditfield(app.UIFigure, 'numeric');
            app.E21.Value = 2;
            app.E22 = uieditfield(app.UIFigure, 'numeric');
            app.E22.Value = -6;
            app.E23 = uieditfield(app.UIFigure, 'numeric');
            app.E23.Value = -1;
            app.E24 = uieditfield(app.UIFigure, 'numeric');
            app.E25 = uieditfield(app.UIFigure, 'numeric');
            app.E31 = uieditfield(app.UIFigure, 'numeric');
            app.E31.Value = -3;
            app.E32 = uieditfield(app.UIFigure, 'numeric');
            app.E32.Value = -2;
            app.E33 = uieditfield(app.UIFigure, 'numeric');
            app.E33.Value = 7;
            app.E34 = uieditfield(app.UIFigure, 'numeric');
            app.E35 = uieditfield(app.UIFigure, 'numeric');
            app.E41 = uieditfield(app.UIFigure, 'numeric');
            app.E42 = uieditfield(app.UIFigure, 'numeric');
            app.E43 = uieditfield(app.UIFigure, 'numeric');
            app.E44 = uieditfield(app.UIFigure, 'numeric');
            app.E45 = uieditfield(app.UIFigure, 'numeric');
            app.E51 = uieditfield(app.UIFigure, 'numeric');
            app.E52 = uieditfield(app.UIFigure, 'numeric');
            app.E53 = uieditfield(app.UIFigure, 'numeric');
            app.E54 = uieditfield(app.UIFigure, 'numeric');
            app.E55 = uieditfield(app.UIFigure, 'numeric');
            app.IngreselamatrizdecoeficientesindependientesLabel = uilabel(app.UIFigure);
            app.IngreselamatrizdecoeficientesindependientesLabel.Text = 'Ingrese la matriz de coeficientes independientes';
            app.ind_1 = uieditfield(app.UIFigure, 'numeric');
            app.ind_1.Value = -38;
            app.ind_2 = uieditfield(app.UIFigure, 'numeric');
            app.ind_2.Value = -34;
            app.ind_3 = uieditfield(app.UIFigure, 'numeric');
            app.ind_3.Value = -20;
            app.ind_4 = uieditfield(app.UIFigure, 'numeric');
            app.ind_5 = uieditfield(app.UIFigure, 'numeric');
            app.ErrorEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorEditFieldLabel.Text = 'Error';
            app.ErrorEditField = uieditfield(app.UIFigure, 'numeric');
            app.ErrorEditField.Value = 0.01;
            app.NmerodeiteracionesEditFieldLabel = uilabel(app.UIFigure);
            app.NmerodeiteracionesEditFieldLabel.Text = 'Número de iteraciones';
            app.NmerodeiteracionesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NmerodeiteracionesEditField.Value = 20;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Iteraciones'; 'x1'; 'x2'; 'x3'; 'x4'; 'x5'; 'Error x1'; 'Error x2'; 'Error x3'; 'Error x4'; 'Error x5'};
            app.UITable.RowName = {};
            app.RealizarButton = uibutton(app.UIFigure, 'push');
            app.RealizarButton.ButtonPushedFcn = createCallbackFcn(app, @RealizarButtonPushed, true);
            app.RealizarButton.Text = 'Realizar';
            app.VolverButton = uibutton(app.UIFigure, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Text = 'Volver';
            app.NmerodeecuacionesEditFieldLabel = uilabel(app.UIFigure);
            app.NmerodeecuacionesEditFieldLabel.Text = 'Número de ecuaciones';
            app.numerodeecuacionesEditField = uieditfield(app.UIFigure, 'numeric');
            app.numerodeecuacionesEditField.Value = 3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = jacobi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end